classdef ImageViewingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ObjectImageUIFigure  matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        FrameEditFieldLabel  matlab.ui.control.Label
        ShowFrameNum         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
         fname;
         imstack;
         im;
         t;
         X;
         Y;
         T;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Load imstack
            app.t = 1;
            app.fname = 'E:\Matlab ilastik\Test.tif';
            app.imstack = readStack(app.fname);
            %Initialize slider
            [app.Y,app.X,app.T] = size(app.imstack);
            app.Slider.Limits = [1 app.T];
            app.Slider.MajorTicks = [1 round(app.T/4) 2*round(app.T/4) 3*round(app.T/4) app.T];
            app.Slider.MinorTicks = [1:app.T];
            app.im = app.imstack(:,:,app.t);
            imshow(app.im,[],'Parent',app.UIAxes)
            
        end

        % Value changed function: Slider
        function change_t(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value = value;
            app.t = value;
            app.ShowFrameNum.Value = app.t;
            app.im = app.imstack(:,:,app.t);
            imshow(app.im,[],'Parent',app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ObjectImageUIFigure = uifigure;
            app.ObjectImageUIFigure.Name = 'ObjectImage';
            app.UIAxes = uiaxes(app.ObjectImageUIFigure);
            title(app.UIAxes, 'Image')
            app.SliderLabel = uilabel(app.ObjectImageUIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ObjectImageUIFigure);
            app.Slider.Limits = [0 20];
            app.Slider.MajorTicks = [0 4 8 12 16 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @change_t, true);
            app.Slider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20];
            app.FrameEditFieldLabel = uilabel(app.ObjectImageUIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.ShowFrameNum = uieditfield(app.ObjectImageUIFigure, 'numeric');
            app.ShowFrameNum.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageViewingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ObjectImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ObjectImageUIFigure)
        end
    end
end